classdef NeopixelControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        OFFButton             matlab.ui.control.Button
        ONButton              matlab.ui.control.Button
        LEDColorButtonGroup   matlab.ui.container.ButtonGroup
        BlueButton            matlab.ui.control.ToggleButton
        GreenButton           matlab.ui.control.ToggleButton
        RedButton             matlab.ui.control.ToggleButton
        RightPanel            matlab.ui.container.Panel
        IntensityValue        matlab.ui.control.NumericEditField
        IntensitySlider       matlab.ui.control.Slider
        IntensitySliderLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','MATLAB:ui:Slider:fixedWidth'); %suppress warning about graphic resizing
            app.GreenButton.Value = true; %Set app defaults to match initial neopixels settings
            app.IntensityValue.Value=20;
            app.IntensitySlider.Value=20;
            global a
            a = arduino('COM5', 'Uno', 'Libraries', 'Adafruit/NeoPixel');
            disp('arduino object created')
            global ring
            ring = addon(a, 'Adafruit/NeoPixel', 'D6', 16);
            writeColor(ring, 1:16, 'green');
            ring.Brightness = 20/100;
            disp('added neopixel ring object')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {458, 458};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: IntensitySlider
        function IntensitySliderValueChanged(app, event)
            global ring
            brightness = round(app.IntensitySlider.Value);
            app.IntensityValue.Value=brightness;
            ring.Brightness = brightness/100;
        end

        % Button pushed function: ONButton
        function ONButtonPushed(app, event)
            global ring
            if app.RedButton.Value == true
                writeColor(ring, 1:16, 'red');
            elseif app.GreenButton.Value == true
                writeColor(ring, 1:16, 'green');
            elseif app.BlueButton.Value == true
                writeColor(ring, 1:16, 'blue');
            end
        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
            global ring
            app.IntensitySlider.Value=0;
            app.IntensityValue.Value=0;
            ring.Brightness = 0;
        end

        % Value changed function: IntensityValue
        function IntensityValueValueChanged(app, event)
            global ring
            brightness = app.IntensityValue.Value;
            app.IntensitySlider.Value=brightness;
            ring.Brightness = brightness/100;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear global a
            clear global ring
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LEDColorButtonGroup = uibuttongroup(app.LeftPanel);
            app.LEDColorButtonGroup.Title = 'LED Color';
            app.RedButton = uitogglebutton(app.LEDColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uitogglebutton(app.LEDColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uitogglebutton(app.LEDColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.ONButton = uibutton(app.LeftPanel, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            app.OFFButton = uibutton(app.LeftPanel, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            app.RightPanel = uipanel(app.GridLayout);
            app.IntensitySliderLabel = uilabel(app.RightPanel);
            app.IntensitySliderLabel.Text = 'Intensity';
            app.IntensitySlider = uislider(app.RightPanel);
            app.IntensitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.IntensitySlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.IntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderValueChanged, true);
            app.IntensityValue = uieditfield(app.RightPanel, 'numeric');
            app.IntensityValue.Limits = [0 100];
            app.IntensityValue.ValueChangedFcn = createCallbackFcn(app, @IntensityValueValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeopixelControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end